classdef NoiseApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        NoiseAxes            matlab.ui.control.UIAxes
        StartEditFieldLabel  matlab.ui.control.Label
        StartEditField       matlab.ui.control.EditField
        EndEditFieldLabel    matlab.ui.control.Label
        EndEditField         matlab.ui.control.EditField
        TimeLabel            matlab.ui.control.Label
        CalculateButton      matlab.ui.control.Button
        Panel_2              matlab.ui.container.Panel
        ImportButton         matlab.ui.control.Button
        ExportButton         matlab.ui.control.Button
        ButtonGroup          matlab.ui.container.ButtonGroup
        ImportfileButton     matlab.ui.control.RadioButton
        ImportfolderButton   matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        Patient;
    end
    
    methods (Access = private)
        
        function results = findOffDutyTime(app)
           
        end
        
        function results = findCompanyInformation(app)
            WorkSchedule = readtable('Data/Noeglefil.csv');
            test = WorkSchedule.VirksomhedsID(WorkSchedule.PatientID == app.Patient.ID);
            test = WorkSchedule.VirksomhedsNavn(WorkSchedule.PatientID == app.Patient.ID); 
        end
    end
    

    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            [FileName, Pathname] = uigetfile();
            if FileName ~=0
                Fullpath = fullfile(Pathname, FileName);
                app.Patient.Measurement = importSingleFileFunction(Fullpath);
                IdCellArray = strsplit(FileName, '.'); %%Splitting Filename into two parts; Filename and file extension
                app.Patient.ID = IdCellArray{1,:};
                findCompanyInformation(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.NoiseAxes = uiaxes(app.Panel);
            title(app.NoiseAxes, 'Title')
            xlabel(app.NoiseAxes, 'Time(s)')
            ylabel(app.NoiseAxes, 'Sound Pressure Level[dB]')
            app.StartEditFieldLabel = uilabel(app.Panel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Panel, 'text');
            app.EndEditFieldLabel = uilabel(app.Panel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.Panel, 'text');
            app.TimeLabel = uilabel(app.Panel);
            app.TimeLabel.Text = 'Time';
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.Text = 'Calculate';
            app.Panel_2 = uipanel(app.UIFigure);
            app.ImportButton = uibutton(app.Panel_2, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ExportButton = uibutton(app.Panel_2, 'push');
            app.ExportButton.Text = 'Export';
            app.ButtonGroup = uibuttongroup(app.Panel_2);
            app.ImportfileButton = uiradiobutton(app.ButtonGroup);
            app.ImportfileButton.Text = 'Import file';
            app.ImportfileButton.Value = true;
            app.ImportfolderButton = uiradiobutton(app.ButtonGroup);
            app.ImportfolderButton.Text = 'Import folder';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NoiseApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end